classdef Inputs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlasmAPP                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExportdatatotxtMenu             matlab.ui.container.Menu
        ImportdatafromtxtMenu           matlab.ui.container.Menu
        ExamplesMenu                    matlab.ui.container.Menu
        TwostreamInsabilityMenu         matlab.ui.container.Menu
        ColdplasmaoscillationsMenu      matlab.ui.container.Menu
        BunemanInstabilityMenu          matlab.ui.container.Menu
        IonacousticwavesMenu            matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        UsermanualMenu                  matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        Run                             matlab.ui.container.toolbar.PushTool
        Reset                           matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        SystemTab                       matlab.ui.container.Tab
        StepsforplotsEditField          matlab.ui.control.NumericEditField
        StepsforplotsEditFieldLabel     matlab.ui.control.Label
        LengthLEditField                matlab.ui.control.EditField
        LengthLEditField_2Label_2       matlab.ui.control.Label
        NumberofbeamsDropDown           matlab.ui.control.DropDown
        NumberofbeamsDropDown_2Label    matlab.ui.control.Label
        NumberofgridpointsNgEditField   matlab.ui.control.NumericEditField
        NumberofgridpointsNgEditField_2Label  matlab.ui.control.Label
        NumberoftimestepsNtEditField    matlab.ui.control.NumericEditField
        NumberoftimestepsNtEditField_2Label  matlab.ui.control.Label
        TimestepdtEditField             matlab.ui.control.NumericEditField
        TimestepdtEditField_2Label      matlab.ui.control.Label
        SpeciesTab                      matlab.ui.container.Tab
        NumberofbeamsButtonGroup        matlab.ui.container.ButtonGroup
        NumberofionsinbackgroundNiEditField  matlab.ui.control.NumericEditField
        NumberofionsinbackgroundNiEditFieldLabel  matlab.ui.control.Label
        Specie2Button                   matlab.ui.control.RadioButton
        ElectronsButton                 matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        MeanVelocityV01EditField        matlab.ui.control.NumericEditField
        MeanVelocityV01EditFieldLabel   matlab.ui.control.Label
        PlasmafrequencyWPEditField      matlab.ui.control.NumericEditField
        PlasmafrequencyWPEditFieldLabel  matlab.ui.control.Label
        Mode1M1EditField                matlab.ui.control.NumericEditField
        Mode1M1EditFieldLabel           matlab.ui.control.Label
        PerturbationamplitudeXP1EditField  matlab.ui.control.NumericEditField
        PerturbationamplitudeXP1EditFieldLabel  matlab.ui.control.Label
        ChargemassrelationQM1EditField  matlab.ui.control.NumericEditField
        ChargemassrelationQM1EditFieldLabel  matlab.ui.control.Label
        ThermalVelocityVth1EditField    matlab.ui.control.NumericEditField
        ThermalVelocityVth1EditFieldLabel  matlab.ui.control.Label
        DensityN1EditField              matlab.ui.control.NumericEditField
        DensityN1EditFieldLabel         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        DensityN2EditField              matlab.ui.control.NumericEditField
        DensityN2EditFieldLabel         matlab.ui.control.Label
        Mode2EditField                  matlab.ui.control.NumericEditField
        Mode2EditFieldLabel             matlab.ui.control.Label
        PerturbationXP2EditField        matlab.ui.control.NumericEditField
        PerturbationXP2EditFieldLabel   matlab.ui.control.Label
        ChargemassrelationQM2EditField  matlab.ui.control.NumericEditField
        ChargemassrelationQM2EditFieldLabel  matlab.ui.control.Label
        ThermalVelocityVth2EditField    matlab.ui.control.NumericEditField
        ThermalVelocityVth2EditFieldLabel  matlab.ui.control.Label
        MeanVelocityV02EditField        matlab.ui.control.NumericEditField
        MeanVelocityV02EditFieldLabel   matlab.ui.control.Label
        NumericalmethodsTab             matlab.ui.container.Tab
        NumericalmethodsforPoissonsequationButtonGroup  matlab.ui.container.ButtonGroup
        FastFourierTransformFFTButton   matlab.ui.control.RadioButton
        FiniteDifferenceMethodButton    matlab.ui.control.RadioButton
        NumericalmethodsforequationsofmotionButtonGroup  matlab.ui.container.ButtonGroup
        EulermethodButton               matlab.ui.control.RadioButton
        RungeKuttaRK4Button             matlab.ui.control.RadioButton
        LeapfrogButton                  matlab.ui.control.RadioButton
        DiagnosticsTab                  matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        Panel4DropDown                  matlab.ui.control.DropDown
        Panel4DropDownLabel             matlab.ui.control.Label
        Panel3DropDown                  matlab.ui.control.DropDown
        Panel3DropDownLabel             matlab.ui.control.Label
        Panel2DropDown                  matlab.ui.control.DropDown
        Panel2DropDownLabel             matlab.ui.control.Label
        Panel1DropDown                  matlab.ui.control.DropDown
        Panel1DropDownLabel             matlab.ui.control.Label
    end

    methods (Access = private)
        %Function that reads the txt. files.
        function read(app,A)
            app.ElectronsButton.Value = 1;
            app.Panel.Visible = 'on';
            app.Panel_2.Visible = 'off';

            Num_beams = num2str(A(5,1));
            Selection_beam(app,Num_beams);

            %Parameters
            app.LengthLEditField.Value = num2str(A(1,1));
            app.TimestepdtEditField.Value = A(2,1);
            app.NumberoftimestepsNtEditField.Value = A(3,1);
            app.NumberofgridpointsNgEditField.Value = A(4,1);
            app.NumberofbeamsDropDown.Value = num2str(A(5,1));
            app.StepsforplotsEditField.Value = A(6,1);

            %Beam 1
            app.DensityN1EditField.Value = A(7,1);
            app.MeanVelocityV01EditField.Value = A(8,1);
            app.ThermalVelocityVth1EditField.Value = A(9,1);
            app.ChargemassrelationQM1EditField.Value = A(10,1);
            app.PerturbationamplitudeXP1EditField.Value = A(11,1);
            app.Mode1M1EditField.Value = A(12,1);
            app.PlasmafrequencyWPEditField.Value = A(13,1);

            %Beam 2
            app.DensityN2EditField.Value = A(14,1);
            app.MeanVelocityV02EditField.Value = A(15,1);
            app.ThermalVelocityVth2EditField.Value = A(16,1);
            app.ChargemassrelationQM2EditField.Value = A(17,1);
            app.PerturbationXP2EditField.Value = A(18,1);
            app.Mode2EditField.Value = A(19,1);

            %Ion Backgroung
            app.NumberofionsinbackgroundNiEditField.Value = A(20,1);
            
            %Hide panel
            Selection_beam(app,Num_beams)
        end

        %Function that hide the beam buttons if a certain number of beams
        %is selected.
        function Selection_beam(app,Num_beams)
            %Num_beams = app.NumberofbeamsDropDown.Value;
            if Num_beams == '1'
                app.ElectronsButton.Visible = 'on';
                app.Specie2Button.Visible = 'off';
            end
            if Num_beams == '2'
                app.ElectronsButton.Visible = 'on';
                app.Specie2Button.Visible = 'on';
            end
        end

        %If a Beam button is selected, then the panel of that beam appears.
        function Beam_Panel(app)
            if app.ElectronsButton.Value == 1
                app.Panel.Visible = 'on';
                app.Panel_2.Visible = 'off';
            end
            if app.Specie2Button.Value == 1
                app.Panel.Visible = 'off';
                app.Panel_2.Visible = 'on';
            end
        end

        %Function that extracts the data
        function data = Extract_data(app,L, dt, Nt, Ng, Num_beams,NPlots, N1, V01, Vth1, QM1, XP1, Mode1,WP, N2, V02, Vth2, QM2, XP2, Mode2, IB)
            global L dt Nt Ng Num_beams N1 V01 Vth1 QM1 XP1 Mode1 WP N2 V02 Vth2 QM2 XP2 Mode2 NPlots IB
            L = str2num(app.LengthLEditField.Value);
            dt = app.TimestepdtEditField.Value;
            Nt = app.NumberoftimestepsNtEditField.Value;
            Ng = app.NumberofgridpointsNgEditField.Value;
            Num_beams = str2double(app.NumberofbeamsDropDown.Value);
            NPlots = app.StepsforplotsEditField.Value;

            % Extract information from Beam 1
            N1 = app.DensityN1EditField.Value;
            V01 = app.MeanVelocityV01EditField.Value;
            Vth1 = app.ThermalVelocityVth1EditField.Value;
            QM1 = app.ChargemassrelationQM1EditField.Value;
            XP1 = app.PerturbationamplitudeXP1EditField.Value;
            Mode1 = app.Mode1M1EditField.Value;
            WP = app.PlasmafrequencyWPEditField.Value;

            % Extract information from Beam 2
            N2 = app.DensityN2EditField.Value;
            V02 = app.MeanVelocityV02EditField.Value;
            Vth2 = app.ThermalVelocityVth2EditField.Value;
            QM2 = app.ChargemassrelationQM2EditField.Value;
            XP2 = app.PerturbationXP2EditField.Value;
            Mode2 = app.Mode2EditField.Value;

            %Ion background
            IB = app.NumberofionsinbackgroundNiEditField.Value;
            
            data = [L dt Nt Ng Num_beams NPlots N1 V01 Vth1 QM1 XP1 Mode1 WP N2 V02 Vth2 QM2 XP2 Mode2 IB]';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global L dt Nt Ng Num_beams N1 V01 Vth1 QM1 XP1 Mode1 WP N2 V02 Vth2 QM2 XP2 Mode2 NPlots IB 
            Num_beams = app.NumberofbeamsDropDown.Value;
            Selection_beam(app,Num_beams)
            data = Extract_data(app,L, dt, Nt, Ng, Num_beams, NPlots, N1, V01, Vth1, QM1, XP1, Mode1, WP, N2, V02, Vth2, QM2, XP2, Mode2, IB);
        end

        % Callback function
        function NumberofbeamsDropDown_3ValueChanged(app, event)
            %Selection of the number of beams
            Num_beams = app.NumberofbeamsDropDown.Value;
            Selection_beam(app,Num_beams);
        end

        % Selection changed function: NumberofbeamsButtonGroup
        function NumberofbeamsButtonGroupSelectionChanged(app, event)
            %If a Beam button is selected, then the panel of that beam appears.
            Beam_Panel(app)
        end

        % Menu selected function: ImportdatafromtxtMenu
        function ImportdatafromtxtMenuSelected(app, event)
            [file,path] = uigetfile('*.txt');
            if file == 0
                return;
            else
                filename = [path file];
                A = importdata(filename);
            end
            read(app,A);
        end

        % Menu selected function: TwostreamInsabilityMenu
        function TwostreamInsabilityMenuSelected(app, event)
            % Two-Stream Instability data example
            A = importdata("1.Two_stream_instability.txt");
            read(app,A);
        end

        % Menu selected function: ExportdatatotxtMenu
        function ExportdatatotxtMenuSelected(app, event)
            global L dt Nt Ng Num_beams N1 V01 Vth1 QM1 XP1 Mode1 WP N2 V02 Vth2 QM2 XP2 Mode2 NPlots IB 
            data = Extract_data(app,L, dt, Nt, Ng, Num_beams, NPlots, N1, V01, Vth1, QM1, XP1, Mode1, WP, N2, V02, Vth2, QM2, XP2, Mode2, IB);
            [fname,pth] = uiputfile('.txt'); % Type in name of file.
            if fname == 0
                return;
            else
                dlmwrite([pth,fname],data)
            end
        end

        % Menu selected function: ColdplasmaoscillationsMenu
        function ColdplasmaoscillationsMenuSelected(app, event)
            A = importdata("2.Cold_Plasma.txt");
            read(app,A);
        end

        % Clicked callback: Run
        function RunClicked(app, event)
            global P1 P2 P3 P4 L dt Nt Ng Num_beams N1 V01 Vth1 QM1 XP1 Mode1 WP N2 V02 Vth2 QM2 XP2 Mode2 NPlots IB 
            data = Extract_data(app,L, dt, Nt, Ng, Num_beams, NPlots, N1, V01, Vth1, QM1, XP1, Mode1, WP, N2, V02, Vth2, QM2, XP2, Mode2, IB);

            %Methods for motion
            Motion_method = app.NumericalmethodsforequationsofmotionButtonGroup.SelectedObject.Text;
            Leap_frog = app.LeapfrogButton.Value;
            RK4 = app.RungeKuttaRK4Button.Value;
            Euler = app.EulermethodButton.Value;

            %Methods for Poisson
            Poisson_method = app.NumericalmethodsforPoissonsequationButtonGroup.SelectedObject.Text;
            FDM = app.FiniteDifferenceMethodButton.Value;
            FFTr = app.FastFourierTransformFFTButton.Value;

            %Panel for plot
            P1 = app.Panel1DropDown.Value;
            P2 = app.Panel2DropDown.Value;
            P3 = app.Panel3DropDown.Value;
            P4 = app.Panel4DropDown.Value;
           
            
           Main(data,Leap_frog,RK4,Euler,FDM,FFTr,P1,P2,P3,P4,Motion_method,Poisson_method)
            %delete(app.PlasmAPP)
        end

        % Value changed function: NumberofbeamsDropDown
        function NumberofbeamsDropDownValueChanged(app, event)
            Num_beams = app.NumberofbeamsDropDown.Value;
            Selection_beam(app,Num_beams)

            switch Num_beams
                case '1'
                    app.DensityN2EditField.Value = 0;
                    app.MeanVelocityV02EditField.Value = 0;
                    app.ThermalVelocityVth2EditField.Value = 0;
                    app.ChargemassrelationQM2EditField.Value = 0;
                    app.PerturbationXP2EditField.Value = 0;
                    app.Mode2EditField.Value = 0;

                    app.DensityN3EditField.Value = 0;
                    app.MeanVelocityV03EditField.Value = 0;
                    app.ThermalVelocityVth3EditField.Value = 0;
                    app.ChargemassrelationQM3EditField.Value = 0;
                    app.PerturbationXP3EditField.Value = 0;
                    app.Mode3EditField.Value = 0;

                case '2'
                    app.DensityN3EditField.Value = 0;
                    app.MeanVelocityV03EditField.Value = 0;
                    app.ThermalVelocityVth3EditField.Value = 0;
                    app.ChargemassrelationQM3EditField.Value = 0;
                    app.PerturbationXP3EditField.Value = 0;
                    app.Mode3EditField.Value = 0;

            end

            
        end

        % Clicked callback: Reset
        function ResetClicked(app, event)
            app.LengthLEditField.Value = num2str(0);
            app.TimestepdtEditField.Value = 0;
            app.NumberoftimestepsNtEditField.Value = 0;
            app.NumberofgridpointsNgEditField.Value = 0;
            app.StepsforplotsEditField.Value = 0;

            app.DensityN1EditField.Value = 0;
            app.MeanVelocityV01EditField.Value = 0;
            app.ThermalVelocityVth1EditField.Value = 0;
            app.ChargemassrelationQM1EditField.Value = 0;
            app.PerturbationamplitudeXP1EditField.Value = 0;
            app.Mode1M1EditField.Value = 0;

            app.DensityN2EditField.Value = 0;
            app.MeanVelocityV02EditField.Value = 0;
            app.ThermalVelocityVth2EditField.Value = 0;
            app.ChargemassrelationQM2EditField.Value = 0;
            app.PerturbationXP2EditField.Value = 0;
            app.Mode2EditField.Value = 0;

            app.DensityN3EditField.Value = 0;
            app.MeanVelocityV03EditField.Value = 0;
            app.ThermalVelocityVth3EditField.Value = 0;
            app.ChargemassrelationQM3EditField.Value = 0;
            app.PerturbationXP3EditField.Value = 0;
            app.Mode3EditField.Value = 0;

            app.NumberofionsinbackgroundNiEditField.Value = 0;
        end

        % Menu selected function: BunemanInstabilityMenu
        function BunemanInstabilityMenuSelected(app, event)
            A = importdata("3.Buneman_instability.txt");
            read(app,A);
        end

        % Menu selected function: IonacousticwavesMenu
        function IonacousticwavesMenuSelected(app, event)
            A = importdata("4.ion_acoustic_waves.txt");
            read(app,A);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlasmAPP = uifigure('Visible', 'off');
            app.PlasmAPP.Name = 'PlasmAPP';
            app.PlasmAPP.Icon = 'PLASMAPP (2).gif';
            app.FileMenu = uimenu(app.PlasmAPP);
            app.FileMenu.Text = 'File';
            app.ExportdatatotxtMenu = uimenu(app.FileMenu);
            app.ExportdatatotxtMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportdatatotxtMenuSelected, true);
            app.ExportdatatotxtMenu.Text = 'Export data to txt.';
            app.ImportdatafromtxtMenu = uimenu(app.FileMenu);
            app.ImportdatafromtxtMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportdatafromtxtMenuSelected, true);
            app.ImportdatafromtxtMenu.Text = 'Import data from txt.';
            app.ExamplesMenu = uimenu(app.FileMenu);
            app.ExamplesMenu.Text = 'Examples';
            app.TwostreamInsabilityMenu = uimenu(app.ExamplesMenu);
            app.TwostreamInsabilityMenu.MenuSelectedFcn = createCallbackFcn(app, @TwostreamInsabilityMenuSelected, true);
            app.TwostreamInsabilityMenu.Text = 'Two-stream Insability';
            app.ColdplasmaoscillationsMenu = uimenu(app.ExamplesMenu);
            app.ColdplasmaoscillationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ColdplasmaoscillationsMenuSelected, true);
            app.ColdplasmaoscillationsMenu.Text = 'Cold plasma oscillations';
            app.BunemanInstabilityMenu = uimenu(app.ExamplesMenu);
            app.BunemanInstabilityMenu.MenuSelectedFcn = createCallbackFcn(app, @BunemanInstabilityMenuSelected, true);
            app.BunemanInstabilityMenu.Text = 'Buneman Instability';
            app.IonacousticwavesMenu = uimenu(app.ExamplesMenu);
            app.IonacousticwavesMenu.MenuSelectedFcn = createCallbackFcn(app, @IonacousticwavesMenuSelected, true);
            app.IonacousticwavesMenu.Text = 'Ion acoustic waves';
            app.OptionsMenu = uimenu(app.PlasmAPP);
            app.OptionsMenu.Text = 'Options';
            app.HelpMenu = uimenu(app.OptionsMenu);
            app.HelpMenu.Text = 'Help';
            app.UsermanualMenu = uimenu(app.HelpMenu);
            app.UsermanualMenu.Text = 'User manual';
            app.Toolbar = uitoolbar(app.PlasmAPP);
            app.Run = uipushtool(app.Toolbar);
            app.Run.ClickedCallback = createCallbackFcn(app, @RunClicked, true);
            app.Run.Icon = 'green-play-button-png.png';
            app.Reset = uipushtool(app.Toolbar);
            app.Reset.ClickedCallback = createCallbackFcn(app, @ResetClicked, true);
            app.Reset.Icon = 'clear.png';
            app.TabGroup = uitabgroup(app.PlasmAPP);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.TimestepdtEditField_2Label = uilabel(app.SystemTab);
            app.TimestepdtEditField_2Label.Text = 'Time step (dt)';
            app.TimestepdtEditField = uieditfield(app.SystemTab, 'numeric');
            app.NumberoftimestepsNtEditField_2Label = uilabel(app.SystemTab);
            app.NumberoftimestepsNtEditField_2Label.Text = 'Number of time steps (Nt)';
            app.NumberoftimestepsNtEditField = uieditfield(app.SystemTab, 'numeric');
            app.NumberofgridpointsNgEditField_2Label = uilabel(app.SystemTab);
            app.NumberofgridpointsNgEditField_2Label.Text = 'Number of grid points (Ng)';
            app.NumberofgridpointsNgEditField = uieditfield(app.SystemTab, 'numeric');
            app.NumberofbeamsDropDown_2Label = uilabel(app.SystemTab);
            app.NumberofbeamsDropDown_2Label.Text = 'Number of beams';
            app.NumberofbeamsDropDown = uidropdown(app.SystemTab);
            app.NumberofbeamsDropDown.Items = {'1', '2'};
            app.NumberofbeamsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofbeamsDropDownValueChanged, true);
            app.NumberofbeamsDropDown.Value = '2';
            app.LengthLEditField_2Label_2 = uilabel(app.SystemTab);
            app.LengthLEditField_2Label_2.Text = 'Length (L)';
            app.LengthLEditField = uieditfield(app.SystemTab, 'text');
            app.LengthLEditField.Tooltip = {''};
            app.LengthLEditField.Value = '0';
            app.StepsforplotsEditFieldLabel = uilabel(app.SystemTab);
            app.StepsforplotsEditFieldLabel.Text = 'Steps for plots';
            app.StepsforplotsEditField = uieditfield(app.SystemTab, 'numeric');
            app.SpeciesTab = uitab(app.TabGroup);
            app.SpeciesTab.Title = 'Species';
            app.Panel_2 = uipanel(app.SpeciesTab);
            app.MeanVelocityV02EditFieldLabel = uilabel(app.Panel_2);
            app.MeanVelocityV02EditFieldLabel.Text = 'Mean Velocity (V02)';
            app.MeanVelocityV02EditField = uieditfield(app.Panel_2, 'numeric');
            app.ThermalVelocityVth2EditFieldLabel = uilabel(app.Panel_2);
            app.ThermalVelocityVth2EditFieldLabel.Text = 'Thermal Velocity (Vth2)';
            app.ThermalVelocityVth2EditField = uieditfield(app.Panel_2, 'numeric');
            app.ChargemassrelationQM2EditFieldLabel = uilabel(app.Panel_2);
            app.ChargemassrelationQM2EditFieldLabel.Text = 'Charge mass relation (QM2)';
            app.ChargemassrelationQM2EditField = uieditfield(app.Panel_2, 'numeric');
            app.PerturbationXP2EditFieldLabel = uilabel(app.Panel_2);
            app.PerturbationXP2EditFieldLabel.Text = 'Perturbation (XP2)';
            app.PerturbationXP2EditField = uieditfield(app.Panel_2, 'numeric');
            app.Mode2EditFieldLabel = uilabel(app.Panel_2);
            app.Mode2EditFieldLabel.Text = 'Mode 2';
            app.Mode2EditField = uieditfield(app.Panel_2, 'numeric');
            app.DensityN2EditFieldLabel = uilabel(app.Panel_2);
            app.DensityN2EditFieldLabel.Text = 'Density (N2)';
            app.DensityN2EditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel = uipanel(app.SpeciesTab);
            app.DensityN1EditFieldLabel = uilabel(app.Panel);
            app.DensityN1EditFieldLabel.Text = 'Density (N1)';
            app.DensityN1EditField = uieditfield(app.Panel, 'numeric');
            app.ThermalVelocityVth1EditFieldLabel = uilabel(app.Panel);
            app.ThermalVelocityVth1EditFieldLabel.Text = 'Thermal Velocity (Vth1)';
            app.ThermalVelocityVth1EditField = uieditfield(app.Panel, 'numeric');
            app.ChargemassrelationQM1EditFieldLabel = uilabel(app.Panel);
            app.ChargemassrelationQM1EditFieldLabel.Text = 'Charge mass relation (QM1)';
            app.ChargemassrelationQM1EditField = uieditfield(app.Panel, 'numeric');
            app.PerturbationamplitudeXP1EditFieldLabel = uilabel(app.Panel);
            app.PerturbationamplitudeXP1EditFieldLabel.Text = 'Perturbation amplitude (XP1)';
            app.PerturbationamplitudeXP1EditField = uieditfield(app.Panel, 'numeric');
            app.Mode1M1EditFieldLabel = uilabel(app.Panel);
            app.Mode1M1EditFieldLabel.Text = 'Mode 1 (M1)';
            app.Mode1M1EditField = uieditfield(app.Panel, 'numeric');
            app.PlasmafrequencyWPEditFieldLabel = uilabel(app.Panel);
            app.PlasmafrequencyWPEditFieldLabel.Text = 'Plasma frequency (WP) ';
            app.PlasmafrequencyWPEditField = uieditfield(app.Panel, 'numeric');
            app.MeanVelocityV01EditFieldLabel = uilabel(app.Panel);
            app.MeanVelocityV01EditFieldLabel.Text = 'Mean Velocity (V01)';
            app.MeanVelocityV01EditField = uieditfield(app.Panel, 'numeric');
            app.NumberofbeamsButtonGroup = uibuttongroup(app.SpeciesTab);
            app.NumberofbeamsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NumberofbeamsButtonGroupSelectionChanged, true);
            app.NumberofbeamsButtonGroup.Title = '   Number of beams';
            app.ElectronsButton = uiradiobutton(app.NumberofbeamsButtonGroup);
            app.ElectronsButton.Text = 'Electrons';
            app.ElectronsButton.Value = true;
            app.Specie2Button = uiradiobutton(app.NumberofbeamsButtonGroup);
            app.Specie2Button.Text = 'Specie 2';
            app.NumberofionsinbackgroundNiEditFieldLabel = uilabel(app.NumberofbeamsButtonGroup);
            app.NumberofionsinbackgroundNiEditFieldLabel.Text = 'Number of ions in background (Ni) ';
            app.NumberofionsinbackgroundNiEditField = uieditfield(app.NumberofbeamsButtonGroup, 'numeric');
            app.NumericalmethodsTab = uitab(app.TabGroup);
            app.NumericalmethodsTab.Title = 'Numerical methods';
            app.NumericalmethodsforequationsofmotionButtonGroup = uibuttongroup(app.NumericalmethodsTab);
            app.NumericalmethodsforequationsofmotionButtonGroup.Title = 'Numerical methods for equations of motion';
            app.LeapfrogButton = uiradiobutton(app.NumericalmethodsforequationsofmotionButtonGroup);
            app.LeapfrogButton.Text = 'Leapfrog';
            app.LeapfrogButton.Value = true;
            app.RungeKuttaRK4Button = uiradiobutton(app.NumericalmethodsforequationsofmotionButtonGroup);
            app.RungeKuttaRK4Button.Text = 'Runge Kutta (RK4)';
            app.EulermethodButton = uiradiobutton(app.NumericalmethodsforequationsofmotionButtonGroup);
            app.EulermethodButton.Text = 'Euler method';
            app.NumericalmethodsforPoissonsequationButtonGroup = uibuttongroup(app.NumericalmethodsTab);
            app.NumericalmethodsforPoissonsequationButtonGroup.Title = 'Numerical methods for Poisson´s equation';
            app.FiniteDifferenceMethodButton = uiradiobutton(app.NumericalmethodsforPoissonsequationButtonGroup);
            app.FiniteDifferenceMethodButton.Text = 'Finite Difference Method';
            app.FiniteDifferenceMethodButton.Value = true;
            app.FastFourierTransformFFTButton = uiradiobutton(app.NumericalmethodsforPoissonsequationButtonGroup);
            app.FastFourierTransformFFTButton.Text = 'Fast Fourier Transform (FFT)';
            app.DiagnosticsTab = uitab(app.TabGroup);
            app.DiagnosticsTab.Title = 'Diagnostics';
            app.Panel1DropDownLabel = uilabel(app.DiagnosticsTab);
            app.Panel1DropDownLabel.Text = 'Panel 1';
            app.Panel1DropDown = uidropdown(app.DiagnosticsTab);
            app.Panel1DropDown.Items = {'Phase space', 'Energy', 'Momentum', 'Distribution function', 'Electric potential', 'Electric field', 'Dispersion relation'};
            app.Panel1DropDown.Value = 'Phase space';
            app.Panel2DropDownLabel = uilabel(app.DiagnosticsTab);
            app.Panel2DropDownLabel.Text = 'Panel 2';
            app.Panel2DropDown = uidropdown(app.DiagnosticsTab);
            app.Panel2DropDown.Items = {'Phase space', 'Energy', 'Momentum', 'Distribution function', 'Electric potential', 'Electric field', 'Dispersion relation'};
            app.Panel2DropDown.Value = 'Energy';
            app.Panel3DropDownLabel = uilabel(app.DiagnosticsTab);
            app.Panel3DropDownLabel.Text = 'Panel 3';
            app.Panel3DropDown = uidropdown(app.DiagnosticsTab);
            app.Panel3DropDown.Items = {'Phase space', 'Energy', 'Momentum', 'Distribution function', 'Electric potential', 'Electric field', 'Dispersion relation'};
            app.Panel3DropDown.Value = 'Momentum';
            app.Panel4DropDownLabel = uilabel(app.DiagnosticsTab);
            app.Panel4DropDownLabel.Text = 'Panel 4';
            app.Panel4DropDown = uidropdown(app.DiagnosticsTab);
            app.Panel4DropDown.Items = {'Phase space', 'Energy', 'Momentum', 'Distribution function', 'Electric potential', 'Electric field', 'Dispersion relation'};
            app.Panel4DropDown.Value = 'Distribution function';
            app.Image = uiimage(app.DiagnosticsTab);
            app.Image.ImageSource = 'PLASMAPP (1).gif';
            % Show the figure after all components are created
            app.PlasmAPP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inputs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlasmAPP)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlasmAPP)
        end
    end
end